classdef Klasifikasi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KlasifikasiButton               matlab.ui.control.Button
        MasukkanGambarButton            matlab.ui.control.Button
        Kelompok4AndiMuhammadFajarMuhAqshaTaufiqMuhGhazaliALabel  matlab.ui.control.Label
        HasilKlasifikasiEditField       matlab.ui.control.EditField
        HasilKlasifikasiEditFieldLabel  matlab.ui.control.Label
        NilaiBlueYellowEditField        matlab.ui.control.NumericEditField
        BlueYellowLabel                 matlab.ui.control.Label
        NilaiRedGreenEditField          matlab.ui.control.NumericEditField
        RedGreenLabel                   matlab.ui.control.Label
        NilaiLightnessEditField         matlab.ui.control.NumericEditField
        LightnessLabel                  matlab.ui.control.Label
        SistemPenentuanTingkatKematanganPadaPotengTapeUbiLabel  matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MasukkanGambarButton
        function MasukkanGambarButtonPushed(app, event)
        currentFolder = pwd;
        [fileName, filePath] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', ...
            'File Gambar (*.jpg, *.jpeg, *.png, *.bmp)'}, 'Pilih Gambar', currentFolder);
    
        if isequal(fileName, 0)
            return;
        else
            app.img = imread(fullfile(filePath, fileName));
            imshow(app.img, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'image');
        end
        
        end

        % Button pushed function: KlasifikasiButton
        function KlasifikasiButtonPushed(app, event)
    % 1. Memastikan gambar telah dimasukkan
    if isempty(app.img)
        uialert(app.UIFigure, 'Silakan masukkan gambar terlebih dahulu.', 'Error');
        return;
    end

    % 2. Mengambil gambar dari properti app
    I = app.img;

    % 3. Mengubah citra ke skala keabuan (grayscale)
    grayImg = rgb2gray(I);

    % Menampilkan citra grayscale di UIAxes_2
    imshow(grayImg, 'Parent', app.UIAxes_2);
     axis(app.UIAxes_2, 'image');

    % 4. Ekstraksi fitur LAB
    labImage = rgb2lab(I);
    LAB_L = labImage(:,:,1);
    LAB_A = labImage(:,:,2);
    LAB_B = labImage(:,:,3);

    % 5. Segmentasi menggunakan metode Otsu
    level = graythresh(grayImg);
    segmentation = imbinarize(grayImg, level);

    % Menampilkan hasil segmentasi Otsu di UIAxes_3
    imshow(segmentation, 'Parent', app.UIAxes_3);
     axis(app.UIAxes_3, 'image');

    % 6. Operasi morfologi
    SE_open = strel('disk', 30);     % Elemen struktural untuk opening dengan radius 30
    SE_dilate = strel('disk', 50);   % Elemen struktural untuk dilasi dengan radius 50
    SE_erosion = strel('disk', 50);  % Elemen struktural untuk erosi dengan radius 50

    % Melakukan operasi opening
    hasilOpening = imopen(segmentation, SE_open);
    % Menampilkan hasil opening di UIAxes_4
    imshow(hasilOpening, 'Parent', app.UIAxes_4);
     axis(app.UIAxes_4, 'image');

    % Melakukan operasi dilasi
    hasilDilasi = imdilate(hasilOpening, SE_dilate);
    % Menampilkan hasil dilasi di UIAxes_5
    imshow(hasilDilasi, 'Parent', app.UIAxes_5);
     axis(app.UIAxes_5, 'image');

    % Membersihkan area kecil
    bersih = bwareaopen(hasilDilasi, 100);
    % Menampilkan hasil pembersihan di UIAxes_6 (opsional)
    % imshow(bersih, 'Parent', app.UIAxes_6);

    % Melakukan erosi
    hasilErosi = imerode(bersih, SE_erosion);

    % Membuat mask dan mengembalikan warna objek
    maskColor = cast(hasilErosi, 'like', I); % Membuat mask 3 channel
    maskedImage = bsxfun(@times, I, maskColor);

    % Menampilkan hasil masking di UIAxes_6
    imshow(maskedImage, 'Parent', app.UIAxes_6);
     axis(app.UIAxes_6, 'image');

    % 7. Mengembalikan nilai LAB pada area objek saja
    LAB_L(~bersih) = 0;
    LAB_A(~bersih) = 0;
    LAB_B(~bersih) = 0;

    % 8. Ekstraksi Fitur Warna LAB
    K_doub_comp = double(bersih);

    %--------LAB L------------------ 
    LAB_L(~K_doub_comp) = 0;
    lab_stats_l = regionprops(K_doub_comp, LAB_L, 'MeanIntensity');
    lab_lightness = lab_stats_l.MeanIntensity;

    %--------LAB A------------------ 
    LAB_A(~K_doub_comp) = 0;
    lab_stats_a = regionprops(K_doub_comp, LAB_A, 'MeanIntensity');
    lab_redgreen = lab_stats_a.MeanIntensity;

    %--------LAB B------------------ 
    LAB_B(~K_doub_comp) = 0;
    lab_stats_b = regionprops(K_doub_comp, LAB_B, 'MeanIntensity');
    lab_blueyellow = lab_stats_b.MeanIntensity;

    % 9. Ekstraksi Fitur Tekstur dengan GLCM
    stats_bentuk = regionprops(K_doub_comp,'BoundingBox');
    if isempty(stats_bentuk)
        % Jika tidak ada objek yang terdeteksi, set nilai ke 0
        contrast = 0;
        correlation = 0;
        energy = 0;
        homogeneity = 0;
    else
        % Mengambil bounding box dari objek
        kropping = imcrop(I, stats_bentuk.BoundingBox);
        kropping_gray = rgb2gray(kropping);
        GLCM2 = graycomatrix(kropping_gray, 'GrayLimits', [0 255], 'NumLevels', 256);
        statsGLCM = graycoprops(GLCM2, {'Contrast', 'Correlation', 'Energy', 'Homogeneity'});
        contrast = statsGLCM.Contrast;
        correlation = statsGLCM.Correlation;
        energy = statsGLCM.Energy;
        homogeneity = statsGLCM.Homogeneity;
    end

    % 10. Normalisasi Fitur
    % Load data normalisasi dan model yang telah dilatih
    load('training_results.mat', 'feature_min', 'feature_max', 'results', 'feature_combinations');

    % Dapatkan indeks fitur untuk kombinasi 'LAB_Tekstur'
    combination_name = 'LAB_Tekstur';
    idx_combination = find(strcmp(feature_combinations(:,1), combination_name));
    feature_indices = feature_combinations{idx_combination, 2};

    % Ekstrak feature_min dan feature_max untuk fitur yang digunakan
    feature_min_used = feature_min(feature_indices);
    feature_max_used = feature_max(feature_indices);

    % Buat vektor fitur
    features = [lab_lightness, lab_redgreen, lab_blueyellow, contrast, homogeneity, energy, correlation];

    % Normalisasi fitur
    features_normalized = (features - feature_min_used) ./ (feature_max_used - feature_min_used);
    features_normalized(isnan(features_normalized)) = 0;

    % 11. Menampilkan fitur yang telah dinormalisasi di EditField
    app.NilaiLightnessEditField.Value = features_normalized(1);       % Nilai Lightness dinormalisasi
    app.NilaiRedGreenEditField.Value = features_normalized(2);        % Nilai Red-Green dinormalisasi
    app.NilaiBlueYellowEditField.Value = features_normalized(3);      % Nilai Blue-Yellow dinormalisasi

    % 12. Klasifikasi menggunakan model yang disimpan
    % Pastikan input berupa vektor kolom
    input = features_normalized';

    % Load model jaringan saraf untuk kombinasi 'LAB_Tekstur'
    net = results.(combination_name).network;

    % Prediksi kelas menggunakan model yang disimpan
    output = net(input);

    % Menentukan kelas berdasarkan output
    if output <= 1.5
        predicted_class = 1; % Kelas Mentah
    else
        predicted_class = 2; % Kelas Matang
    end

    % Menampilkan hasil klasifikasi
    if predicted_class == 1
        hasil = 'Mentah';  % Kelas 1 untuk 'Mentah'
    elseif predicted_class == 2
        hasil = 'Matang';  % Kelas 2 untuk 'Matang'
    else
        hasil = 'Unknown';  % Kelas tidak dikenali
    end

    % 13. Menampilkan hasil pada EditField
    app.HasilKlasifikasiEditField.Value = hasil;
        end

        % Callback function
        function BersihkanButtonPushed(app, event)
  % 1. Menghapus gambar pada UIAxes
    cla(app.UIAxes);     % Menghapus gambar asli
    cla(app.UIAxes_2);   % Menghapus grayscale
    cla(app.UIAxes_3);   % Menghapus segmentasi Otsu
    cla(app.UIAxes_4);   % Menghapus hasil opening
    cla(app.UIAxes_5);   % Menghapus hasil dilasi
    cla(app.UIAxes_6);   % Menghapus hasil masking

    % 2. Mengosongkan nilai pada EditField
    app.NilaiLightnessEditField.Value = [];          % Mengatur Lightness ke kosong
    app.NilaiRedGreenEditField.Value = [];           % Mengatur Red-Green ke kosong
    app.NilaiBlueYellowEditField.Value = [];         % Mengatur Blue-Yellow ke kosong
    app.HasilKlasifikasiEditField.Value = '';   % Mengatur Hasil Klasifikasi ke string kosong

    % 3. Tidak menghapus gambar yang tersimpan dalam app.img
    % app.img tetap ada untuk digunakan kembali
s
    % 4. (Opsional) Menampilkan pesan konfirmasi
    uialert(app.UIFigure, 'Semua tampilan telah dibersihkan.', 'Informasi');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Citra Asli')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Citra Grayscale')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Segmentasi Otsu')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Hasil Opening')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Hasil Dilasi')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.ZTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Hasil Mask')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.ZTick = [];
            app.SistemPenentuanTingkatKematanganPadaPotengTapeUbiLabel = uilabel(app.UIFigure);
            app.SistemPenentuanTingkatKematanganPadaPotengTapeUbiLabel.Text = 'Sistem Penentuan Tingkat Kematangan Pada Poteng (Tape Ubi)';
            app.LightnessLabel = uilabel(app.UIFigure);
            app.LightnessLabel.Text = 'Nilai Lightness:';
            app.NilaiLightnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedGreenLabel = uilabel(app.UIFigure);
            app.RedGreenLabel.Text = 'Nilai Red-Green:';
            app.NilaiRedGreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlueYellowLabel = uilabel(app.UIFigure);
            app.BlueYellowLabel.Text = 'Nilai Blue-Yellow:';
            app.NilaiBlueYellowEditField = uieditfield(app.UIFigure, 'numeric');
            app.HasilKlasifikasiEditFieldLabel = uilabel(app.UIFigure);
            app.HasilKlasifikasiEditFieldLabel.Text = 'Hasil Klasifikasi';
            app.HasilKlasifikasiEditField = uieditfield(app.UIFigure, 'text');
            app.Kelompok4AndiMuhammadFajarMuhAqshaTaufiqMuhGhazaliALabel = uilabel(app.UIFigure);
            app.Kelompok4AndiMuhammadFajarMuhAqshaTaufiqMuhGhazaliALabel.Text = {'Kelompok 4:'; 'Andi Muhammad Fajar'; 'Muh. Aqsha Taufiq'; 'Muh. Ghazali A.'; 'Dosen Pengampuh: Andi Baso Kaswar'};
            app.MasukkanGambarButton = uibutton(app.UIFigure, 'push');
            app.MasukkanGambarButton.ButtonPushedFcn = createCallbackFcn(app, @MasukkanGambarButtonPushed, true);
            app.MasukkanGambarButton.Text = 'Masukkan Gambar';
            app.KlasifikasiButton = uibutton(app.UIFigure, 'push');
            app.KlasifikasiButton.ButtonPushedFcn = createCallbackFcn(app, @KlasifikasiButtonPushed, true);
            app.KlasifikasiButton.Text = 'Klasifikasi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Klasifikasi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end